classdef First < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        TrussAnalysisLabel  matlab.ui.control.Label
        IncrementLabel      matlab.ui.control.Label
        MaximumLabel        matlab.ui.control.Label
        MinimumLabel        matlab.ui.control.Label
        WeightLabel         matlab.ui.control.Label
        LengthLabel         matlab.ui.control.Label
        wi                  matlab.ui.control.NumericEditField
        wM                  matlab.ui.control.NumericEditField
        wm                  matlab.ui.control.NumericEditField
        li                  matlab.ui.control.NumericEditField
        lM                  matlab.ui.control.NumericEditField
        lm                  matlab.ui.control.NumericEditField
        ResetButton         matlab.ui.control.Button
        CalculateButton     matlab.ui.control.Button
        FBDButton           matlab.ui.control.Button
        QuestionButton      matlab.ui.control.Button
    end

    
    properties (Access = private)
        newapp % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.li.Value = 0;
            app.lm.Value = 0;
            app.lM.Value = 0;
            app.wi.Value = 0;
            app.wm.Value = 0;
            app.wM.Value = 0;          
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            lii = app.li.Value;
            lmm = app.lm.Value;
            lMM = app.lM.Value;
            wii = app.wi.Value;
            wmm = app.wm.Value;
            wMM = app.wM.Value;
            
            w = zeros(10,1)
            l = zeros(10,1)
            Ay = zeros(10,1)
            T = zeros(10,1)
            
            for i = 1:11
                if wmm<=wMM
                    w(i) = wmm
                    l(i) = lmm
                    Ay(i) = wmm/2;
                    wmm = wmm+wii
                    lmm = lmm+lii
                end
            end
            T = Ay
            app.newapp = Second(app,w,l,Ay,T)
        end

        % Button pushed function: QuestionButton
        function QuestionButtonPushed(app, event)
            app.newapp = four;
        end

        % Button pushed function: FBDButton
        function FBDButtonPushed(app, event)
            app.newapp = Five;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.QuestionButton = uibutton(app.UIFigure, 'push');
            app.QuestionButton.ButtonPushedFcn = createCallbackFcn(app, @QuestionButtonPushed, true);
            app.QuestionButton.Text = 'Question';
            app.FBDButton = uibutton(app.UIFigure, 'push');
            app.FBDButton.ButtonPushedFcn = createCallbackFcn(app, @FBDButtonPushed, true);
            app.FBDButton.Text = 'FBD';
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate ';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.lm = uieditfield(app.UIFigure, 'numeric');
            app.lm.Value = 0.25;
            app.lM = uieditfield(app.UIFigure, 'numeric');
            app.lM.Value = 2;
            app.li = uieditfield(app.UIFigure, 'numeric');
            app.li.Value = 0.1945;
            app.wm = uieditfield(app.UIFigure, 'numeric');
            app.wm.Value = 100;
            app.wM = uieditfield(app.UIFigure, 'numeric');
            app.wM.Value = 1000;
            app.wi = uieditfield(app.UIFigure, 'numeric');
            app.wi.Value = 100;
            app.LengthLabel = uilabel(app.UIFigure);
            app.LengthLabel.Text = 'Length';
            app.WeightLabel = uilabel(app.UIFigure);
            app.WeightLabel.Text = 'Weight';
            app.MinimumLabel = uilabel(app.UIFigure);
            app.MinimumLabel.Text = 'Minimum';
            app.MaximumLabel = uilabel(app.UIFigure);
            app.MaximumLabel.Text = 'Maximum';
            app.IncrementLabel = uilabel(app.UIFigure);
            app.IncrementLabel.Text = 'Increment';
            app.TrussAnalysisLabel = uilabel(app.UIFigure);
            app.TrussAnalysisLabel.Text = 'Truss Analysis';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = First

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end