classdef Second < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        NoButton                        matlab.ui.control.Button
        YesButton                       matlab.ui.control.Button
        DoyouwanttosavetheresultsLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        newapp % Description
        callingapp % Description
        w
        l
        ay
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, First, w, l, Ay, T)
           app.callingapp = First;
           app.w = w;
           app.l = l;
           app.ay = Ay;
        end

        % Button pushed function: YesButton
        function YesButtonPushed(app, event)
            
            w = app.w;
            l = app.l;
            ay = app.ay;
           [file,path] = uiputfile('Answer.mat','Save file name');
            fullFileName = fullfile(path, file);
            save(fullFileName,'w','l','ay','ay');
    
            
            X=[w,l,ay,ay]
            xlswrite('Results.xls',X)
        end

        % Button pushed function: NoButton
        function NoButtonPushed(app, event)
            app.newapp = Third(app,app.w,app.l,app.ay);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DoyouwanttosavetheresultsLabel = uilabel(app.UIFigure);
            app.DoyouwanttosavetheresultsLabel.Text = 'Do you want to save the results?';
            app.YesButton = uibutton(app.UIFigure, 'push');
            app.YesButton.ButtonPushedFcn = createCallbackFcn(app, @YesButtonPushed, true);
            app.YesButton.Text = 'Yes';
            app.NoButton = uibutton(app.UIFigure, 'push');
            app.NoButton.ButtonPushedFcn = createCallbackFcn(app, @NoButtonPushed, true);
            app.NoButton.Text = 'No';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Second(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end